classdef Vortex_traj < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        CalculButton              matlab.ui.control.Button
        Switch                    matlab.ui.control.RockerSwitch
        TimeforeachframeminLabel  matlab.ui.control.Label
        EditField_2               matlab.ui.control.NumericEditField
        SeguirtodosCheckBox       matlab.ui.control.CheckBox
        Label_2                   matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        VelocitycreepnmminLabel   matlab.ui.control.Label
        JittermotionnmminLabel    matlab.ui.control.Label
        FollowButton              matlab.ui.control.Button
        DistancenmLabel           matlab.ui.control.Label
        EditField                 matlab.ui.control.NumericEditField
        FramesButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        %Aquí metemos las variables globales del programa
        coordenadas
        distance 
        seguimiento
        largo
        tiempo
        interruptor


        Vorticef
       

        Creep
        Jitter
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FramesButton
        function FramesButtonPushed(app, event)


            [file,path] = uigetfile('*.mat','MultiSelect','on');

            if isequal(file, 0) || isequal(path, 0)
                disp('Selección de archivos cancelada.');
                return;
            end


            for i = 1:length(file)
                % Construye la ruta completa del archivo
                fullFilePath = fullfile(path, file{i});

                % Leer el archivo y almacenar los datos en la celda
                fileData = importdata(fullFilePath);
                app.coordenadas{i} = fileData;
            end
            app.largo = length(file);


            app.CalculButton.Enable='on';
            app.FollowButton.Enable='on';


            fig = figure(1);
            imshow(app.coordenadas{1,1}.Map)
            hold on
            triplot(app.coordenadas{1,1}.trian,app.coordenadas{1,1}.centrosc(:,1),app.coordenadas{1,1}.centrosc(:,2))
            set(gca, 'ydir', 'reverse')
            hold on
            plot(app.coordenadas{1,1}.centrosc(:,1),app.coordenadas{1,1}.centrosc(:,2),'r.','MarkerSize',20)


        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            %Esto es para la corrección de distancias

            app.distance = value;
        end

        % Button pushed function: FollowButton
        function FollowButtonPushed(app, event)
            [app.Vorticef,Vortice] = Follow(app.coordenadas,app.seguimiento,app.largo);
           
            % Representamos las trayectorias famosas
            tray = figure(2);
            imshow(app.coordenadas{1,1}.Map)
            hold on

            for l=1:length(Vortice(:,1))


                %plot(Vorticef(:,1,l), Vorticef(:,2,l),'-','MarkerSize',5,'LineWidth',3)
                plot(app.Vorticef(:,1,l), app.Vorticef(:,2,l),'o-r','MarkerSize',7,'MarkerEdgeColor','k','LineWidth',2,'MarkerFaceColor','r')
               
                hold on


                set(gca, 'ydir', 'reverse')
                grid on
               

            end

            app.Label.Visible="on"
            app.Label_2.Visible="on"

           [app.Jitter,app.Creep] = Calcul(app.Vorticef,Vortice,app.largo,app.distance,app.tiempo);
           app.Label.Text = num2str(app.Jitter);
           app.Label_2.Text = num2str(app.Creep);

           tray.WindowButtonDownFcn = {@Click,app.Vorticef,app.largo,app.distance};
        end

        % Value changed function: SeguirtodosCheckBox
        function SeguirtodosCheckBoxValueChanged(app, event)
            value = app.SeguirtodosCheckBox.Value;
            app.seguimiento = value;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            %Este es para el tiempo

            app.tiempo = value;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.interruptor = value; 
        end

        % Button pushed function: CalculButton
        function CalculButtonPushed(app, event)
            angulos = Calculoss(app.Vorticef,app.largo);

            figure(3)
            histogram(angulos,50)
            xlabel('Angle(\o)')  
            ylabel('Counts')


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FramesButton = uibutton(app.UIFigure, 'push');
            app.FramesButton.ButtonPushedFcn = createCallbackFcn(app, @FramesButtonPushed, true);
            app.FramesButton.Text = 'Frames';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.LowerLimitInclusive = 'off';
            app.EditField.Limits = [0 Inf];
            app.EditField.ValueDisplayFormat = '%11.4g nm';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 1;
            app.DistancenmLabel = uilabel(app.UIFigure);
            app.DistancenmLabel.Text = 'Distance (nm)';
            app.FollowButton = uibutton(app.UIFigure, 'push');
            app.FollowButton.ButtonPushedFcn = createCallbackFcn(app, @FollowButtonPushed, true);
            app.FollowButton.Enable = 'off';
            app.FollowButton.Text = 'Follow';
            app.JittermotionnmminLabel = uilabel(app.UIFigure);
            app.JittermotionnmminLabel.Text = 'Jitter motion (nm/min)';
            app.VelocitycreepnmminLabel = uilabel(app.UIFigure);
            app.VelocitycreepnmminLabel.Text = 'Velocity creep (nm/min)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Visible = 'off';
            app.SeguirtodosCheckBox = uicheckbox(app.UIFigure);
            app.SeguirtodosCheckBox.ValueChangedFcn = createCallbackFcn(app, @SeguirtodosCheckBoxValueChanged, true);
            app.SeguirtodosCheckBox.Text = 'Seguir todos';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.LowerLimitInclusive = 'off';
            app.EditField_2.Limits = [0 Inf];
            app.EditField_2.ValueDisplayFormat = '%11.4g min';
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 1;
            app.TimeforeachframeminLabel = uilabel(app.UIFigure);
            app.TimeforeachframeminLabel.Text = {'Time for '; 'each frame (min)'};
            app.Switch = uiswitch(app.UIFigure, 'rocker');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.CalculButton = uibutton(app.UIFigure, 'push');
            app.CalculButton.ButtonPushedFcn = createCallbackFcn(app, @CalculButtonPushed, true);
            app.CalculButton.Enable = 'off';
            app.CalculButton.Text = 'Calcul';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Vortex_traj

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end